classdef trajectory_planning < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UIAxes                  matlab.ui.control.UIAxes
        xyEditFieldLabel        matlab.ui.control.Label
        xyEditField             matlab.ui.control.EditField
        generateBUTTON          matlab.ui.control.Button
        clearBUTTON             matlab.ui.control.Button
        UITable                 matlab.ui.control.Table
        Acc_limLabel            matlab.ui.control.Label
        alimEDIT                matlab.ui.control.NumericEditField
        methorLabel             matlab.ui.control.Label
        methorSELECT            matlab.ui.control.DropDown
        saveButton              matlab.ui.control.Button
        pathLabel               matlab.ui.control.Label
        pathField               matlab.ui.control.EditField
        selectBUTTON            matlab.ui.control.StateButton
        OriginLabel             matlab.ui.control.Label
        originEDIT              matlab.ui.control.EditField
        UpperRightLabel         matlab.ui.control.Label
        uprightEDIT             matlab.ui.control.EditField
        DegreeEditFieldLabel    matlab.ui.control.Label
        degreeEDIT              matlab.ui.control.NumericEditField
        Vel_limEditFieldLabel   matlab.ui.control.Label
        vlimEDIT                matlab.ui.control.NumericEditField
        Jerk_limEditFieldLabel  matlab.ui.control.Label
        JlimEDIT                matlab.ui.control.NumericEditField
        dtEditFieldLabel        matlab.ui.control.Label
        dtEDIT                  matlab.ui.control.NumericEditField
        addBUTTON               matlab.ui.control.Button
        ButtonGroup             matlab.ui.container.ButtonGroup
        Button                  matlab.ui.control.RadioButton
        Button_2                matlab.ui.control.RadioButton
        Button_3                matlab.ui.control.RadioButton
        DimensionLabel          matlab.ui.control.Label
    end

    %   生成轨迹的参数
    properties (Access = private)
       a = 0;
       jerk = 0;
       velocity = 0;
       SampleTime = 0.01;
       dimension = 2 
       degree = 3;
    end
    %   选择点属性
    properties (Access = private)
       SelectedPointSet = []; % 用户选择点, 用户选择点对应时间
       SelectedNum = 0; % 用户选择点数
    end
    %   生成点属性
    properties (Access = private)
       GeneratedPointSet = []; % 算法生成路径点
       GeneratedTimeSet = []; % 算法生成路径点对应时间
    end
    %   UI控件属性
    properties (Access = private)
       IsAllowToSelect = false ;
       AxesLim = [0,1,0,1];
       SelectMethor = '2';
       tablewidth = 0;
       %编辑框文本之间x距离60
       null_position = [];
    end
    %   保存数据属性
    properties (Access = private)
       path='D:\';
    end
    methods (Access = private)
        function output = sign0(app,input)
            if input >=0
                output = 1;
            else
                output = -1;
            end
        end
       
        function [po,to]=LFPB(app,pi,ti,ai,dt)
            n=length(pi); %长度
            to=ti(1):dt:ti(n); %时间点
            
            %在起始点左侧虚拟半段，参数后缀：时间段开始点参数为1，时间段结束点参数为2，中间直线速度为v1，结束点右边直线速度为v2
            h = pi(2)-pi(1); td = ti(2)-ti(1); 
            ab2 = sign0(app,h) * abs(ai);
            tb2 = td - sqrt( td^2 - 2*h/ab2 ); tb2=tb2*2; %乘2是为了和后面的抛物线时间段长度一致，左右关于过点对称
            v2 = h/(td - 0.25*tb2);
            
            c=zeros(3,3); %时间段内轨迹参数，并初始化起点的抛物线参数。
            c(3,1) = ab2/2; c(3,2) = v2 - 2*c(3,1)*(ti(1)+tb2/2); c(3,3) = pi(1) - ( c(3,1)*ti(1)^2 + c(3,2)*ti(1) );
            
            %最后一个点计算
            he = pi(end)-pi(end-1); tde = ti(end)-ti(end-1);
            abe = -sign0(app,he) * abs(ai);
            tbe = tde - sqrt( tde^2 + 2*he/abe ); tbe=tbe*2;
            ve =  he/(tde - 0.25*tbe);
            
            %迭代标记
            k=1;
            count=2;%计数
            temp = 0; %
            po=zeros(1,length(to));
            %计算时间段结束点参数2
            for t=to(2:end) %从第二个点开始，第一个点为pi(1)
                 if t>ti(k) %当前时间进入 ti(k)~ti(k+1)
                        k = k+1;%更新计算点为时间段结束点，计算参数2：ab2、ab2
                    
                    if k == n %计算点是最后一个点
                       tb1 = tb2; v1 = v2; %更新时间段开始参数为上一个时间段结束点参数。
                       ab2 = abe; tb2 = tbe; %时间段结束点参数为之前计算的终止点参数。
                    elseif k == n-1 %计算点是倒数第二个点，比较特殊，因为倒数第二个点右侧的速度是计算好的，必须是终止点直线速度。
                       tb1 = tb2; v1 = v2;%更新时间段开始参数为上一个时间段结束点参数。
                       v2 = ve; %倒数第二点为结束点的时间段结束点的直线时间是和终止点一样的。
                       ab2 = sign0(app,v2 - v1)*abs(ai); 
                       tb2 = (v2-v1)/ab2;
                    else
                       tb1 = tb2; v1 = v2; %更新时间段开始参数为上一个时间段结束点参数。
                       v2 =  (pi(k+1)-pi(k) )/( ti(k+1)-ti(k) ); %时间段结束点右侧直线速度由两个点之间的连线斜率计算。
                       ab2 = sign0(app,v2 - v1)*abs(ai); 
                       tb2 = (v2-v1)/ab2;
                    end
                    
                    if (tb1+tb2)/2> ti(k)-ti(k-1)  %直线段不存在
                        t1 = ti(k) - tb2/2;
                        t2 = t1;
                        warning('加速度过小，直线加速度段消失，优先满足时间段结束点抛物线时间，结果可能有误');
                    else
                        t1 = ti(k-1) + tb1/2 ; t2 = ti(k) - tb2/2 ; %更新直线段起始和结束点
                    end
                    %分段轨迹参数计算
                    c(1,1) = c(3,1) ; c(1,2) = c(3,2) ; c(1,3) = c(3,3) ; %本时间段起始抛物线为上个时间段结束点抛物线参数，故只需要赋值。
                    temp = c(1,1)*t1^2 + c(1,2)*t1 + c(1,3); %计算直线段起始点
            
                    c(2,1) = v1 ; c(2,2) = temp -  t1 *c(2,1); %计算直线参数
                    temp = c(2,1)*t2 + c(2,2); %计算直线参数结束点
            
                    c(3,1) = ab2/2 ; c(3,2) = v1 - 2*c(3,1)*t2 ; c(3,3) = temp - ( c(3,1)*t2^2 + c(3,2)*t2 );%计算时间段结束点处抛物线参数
               end
                if t<t1
                    po(count) = c(1,1)*t^2 + c(1,2)*t + c(1,3);
                elseif t<t2 
                    po(count) = c(2,1) * t + c(2,2);
                else
                    po(count) = c(3,1)*t^2 + c(3,2)*t + c(3,3);
                end
                count=count+1;
            end
            po(1)=pi(1);
        end
    
        function [pout,tout]=LFDS_p2p(app,of,lim,to,dt)     
            po = of(1); pf = of(2); vo = of(3) ; vf = of(4) ; %ao = of(5) ; af =of(6);
            vmax = lim(1) ; amax = lim(2) ; jmax = lim(3) ;
            
            sigma = sign(pf - po);  
            po = sigma*po;
            pf = sigma*pf;
            vo = sigma*vo;
            vf = sigma*vf;
            vmax = ((sigma+1)/2)*vmax + ((sigma-1)/2)*(-vmax);
            amax = ((sigma+1)/2)*amax + ((sigma-1)/2)*(-amax);
            jmax = ((sigma+1)/2)*jmax + ((sigma-1)/2)*(-jmax);
            
            alima = 0; alimd = 0; vlim = 0;
            Ta = 0 ; Td = 0; Tj1 = 0 ; Tj2 = 0;
            if (vmax-vo)*jmax < amax^2 %判断规划过程 加速度是否达到 amax
                Tj1 = sqrt( (vmax-vo)/jmax ) ;
                Ta = 2*Tj1;
                alima = jmax * Tj1;
            else %能达到amax
                Tj1 = amax/jmax ;
                Ta = Tj1 + (vmax - vo)/amax;
                alima = amax;
            end
                
            if (vmax-vf)*jmax <amax^2 % 判断规划过程 加速度是否达到 -amax
                Tj2 = sqrt( (vmax - vf)/jmax ) ;
                Td = 2*Tj2 ;
                alimd = -jmax*Tj1;
            else    %能达到 -amax
                Tj2 = amax/jmax ;
                Td = Tj2 + (vmax - vf)/amax ;
                alimd = -amax;
            end
            
            Tv = (pf - po)/vmax - Ta/2*(1+ vo/vmax) - Td/2*(1+ vf/vmax);
            
            
            if Tv < 0 %不存在匀速时间段， 即最大速度达不到 vmax
                Tv=0 ;
                while 1
                    Tj1 = amax/jmax ; Tj2 = Tj1 ;
                    delta = amax^4/jmax^2 + 2*(vo^2 + vf^2) + amax*(4*(pf-po)-2*amax/jmax*(vo+vf));
                    Ta = ( amax^2/jmax - 2*vo + sqrt(delta) )/(2*amax);
                    Td = ( amax^2/jmax - 2*vf + sqrt(delta) )/(2*amax);
            
                    if Ta <0 || Td <0 %没有加速段（第一个)或没有减速段（第二个s）
                        if Ta < 0  %没有加速段（第一个)
                            Ta = 0; Td = 2*(pf -po)/(vo+vf);
                            Tj1 = 0; Tj2 = (jmax*(pf-po)- sqrt(jmax*( jmax*(pf-po)^2 + (vf+vo)*(vf+vo)*(vf-vo) ) ) )/(jmax*(vf+vo)); 
                            alima = 0;
                            alimd = -jmax*Tj2;
                            vlim = vo;
                        else  %没有减速段（第二个s）
                            Ta = 2*(pf -po)/(vo+vf); Td=0; 
                            Tj1 = (jmax*(pf-po)- sqrt(jmax*( jmax*(pf-po)^2 - (vf+vo)*(vf+vo)*(vf-vo) )) )/(jmax*(vf+vo)); Tj2 = 0;
                            alima = jmax*Tj1;
                            alimd = 0;
                            vlim = vo + alima*(Ta - Tj1);
                        end
                        break;
                    else %第八步
                        if Ta>=2*Tj1 && Td >=2*Tj2 %能达到最大加速度，即存在匀加速阶段。
                            alima = amax;
                            alimd = -amax;
                            vlim = vo + alima*(Ta - Tj1);
                            break;
                        else
                            amax = amax * 0.99;
                        end
                    end
                end
            else
                vlim=vmax;
            end
            %%
            T=Ta+Td+Tv;
            tout=to:dt:T;
            count=1;
            pout=zeros(1,length(tout));
            for t=tout
                if t <= Tj1
                    pout(count) = po + vo*t + (jmax*t^3)/6;
                elseif  t<= Ta-Tj1
                    pout(count) = po + vo*t + (alima/6)*( 3*t^2 - 3*Tj1*t + Tj1^2 );
                elseif  t<= Ta
                    pout(count) = po + (vlim+vo)*Ta/2 - vlim*(Ta-t) + jmax/6*(Ta-t)^3; 
                    
                elseif  t<= Ta+Tv
                    pout(count) = po + (vlim+vo)*Ta/2 + vlim*(t-Ta);
                    
                elseif  t<= T-Td + Tj2
                    pout(count) = pf - (vlim+vf)*Td/2 + vlim*(t-T+Td) - (jmax*(t-T+Td)^3)/6;
                elseif  t<= T-Tj2
                    pout(count) = pf - (vlim+vf)*Td/2 + vlim*(t-T+Td) + (alimd/6)*( (3*(t-T+Td)^2) -3*Tj2*(t-T+Td)+Tj2^2); %注意公式中是alimd ，是负的？？注意公式中lim，min，limd，lima的意思。
                elseif  t<= T
                    pout(count) = pf - vf*(T-t) - jmax*power((T-t),3) /6;
                end
                
                count = count + 1;
            end
                pout=sigma*pout;
        end
        
        function [pout,tout]=LFDS(app,pi,vlim,alim,jiim,dt)
            lim = [vlim,alim,jiim];
    
        end
        
        function ClearData(app)
            cla(app.UIAxes);%清空 数据           
            app.SelectedNum = 0;
            app.SelectedPointSet = [];
            app.GeneratedPointSet = [];
            app.GeneratedTimeSet = [];
            
            %情况 表格
            app.UITable.Data=[];
        end


        function  edit_lable_switch(app,label,edit,state)
            switch state
                case 'off'
                    label.Visible = 'off';
                    label.Position = app.null_position(1:4);
                    edit.Visible = 'off';
                    edit.Position = app.null_position(5:8);
                case 'on'
                    label.Visible = 'on';
                    label.Position = edit.UserData(1:4);
                    edit.Visible = 'on';
                    edit.Position = edit.UserData(5:8);      
            end
        end


        function [knot_table,m] = gen_knot(app,n,p,type)
        %m：节点数m+1个。[u0,...,um]
        %n：控制点个数。 [P0,...,Pn]
        %p：曲线次数。   p
        %type：曲线种   类. 1：open 2：clampped 3：closed
        %默认均匀分布
        m = n + p + 1;
            switch type
                case 1
                    knot_table = (0:1/m:1);
                case 2
                    temp = m - 2 * p ;
                    knot_table = (0:1/temp:1);
                    knot_table = [0*ones(1,p),knot_table];
                    knot_table = [knot_table,1*ones(1,p)];
                case 3
            end
        end
        
        %% BasisFunc 求得N_{i-p+0}^{p}(u) ~ N_{i-p+p}^{p}(u) 其余为0.
        function N = BasisFunc(app,U,u,i,p)
        
            temp = 0; acc = 0;  
            DR = []; DL = [];
            N = []; %从左边开始算起
            N(1) = 1;
            for j = 1:p %i j 都按从0开始的，下面的idx +1 是因为matlab idx从1开始
                DL(j +1) = u - U(i+1-j +1);
                DR(j +1) = U(i+j +1) - u;
                acc = 0;
                for r=0:j-1
                    temp = N(r +1) / (DR(r+1 +1)+DL(j-r +1));
                    N(r +1) = acc + DR(r+1 +1)*temp;
                    acc = DL(j-r +1)*temp;
                end
                N(j +1) = acc;
            end
        end 
        
        %% WhichSpan:二分法查找u所在细分节点区间
        function i = WhichSpan(app,U,u,m,p)
            high = m + 1 - p;
            low = p + 1 ;
            mid = 0;
            if u == U(high)
                mid = high ;
            else
                mid = fix(( high + low )/2); %fix 函数截取整数
                while u < U(mid) || u >= U(mid+1) %当 mid<=u && u<=mid+1 时 idx = mid, i = mid -1
                    if u == U(mid+1)
                       mid = mid + 1;
                    else
                        if u > U(mid)
                            low = mid;
                        else 
                            high = mid ;
                        end
                        mid = fix(( high + low )/2);
                    end
                end
            end 
            i = mid - 1;
        end


        function [pout,tout] = B_spline(app,pi,ti,degree,dt)
            %pi是点列，列数表示维数。
            dim = length(pi(1,:));
            n = length(pi(:,1))-1;
            p = degree;
            [knot,m] = gen_knot(app,n,p,2);
            
            
            tmax = ti(end); tmin = ti(1);
            tout = tmin:dt:tmax-dt;

            pout = zeros(length(tout),dim);
            count = 0;
            for u = (tout-tmin)/(tmax - tmin)
                count = count + 1;
                i = WhichSpan(app,knot,u,m,p); %m = m+1 -1  ,得到 u ∈ [u_i,u_i+1]
                N = BasisFunc(app,knot,u,i,p);
                
                
                for d=1:dim
                    for j=1:p+1 
                        pout(count,d) = pout(count,d) +  pi(i - p + j,d) *N(j);
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.null_position = [app.methorLabel.Position,app.methorSELECT.Position] + [500,0,0,0,500,0,0,0];
            app.degreeEDIT.UserData = [app.DegreeEditFieldLabel.Position,app.degreeEDIT.Position];
            app.alimEDIT.UserData = [app.Acc_limLabel.Position,app.alimEDIT.Position];
            app.vlimEDIT.UserData = [app.Vel_limEditFieldLabel.Position,app.vlimEDIT.Position];
            app.JlimEDIT.UserData = [app.Jerk_limEditFieldLabel.Position,app.JlimEDIT.Position];
            
            app.tablewidth = app.UITable.ColumnWidth{1}(1)*3; 
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
           %判断 是否允许选择
           if app.IsAllowToSelect == false
              return;
           end   
        
           %判断 是否在坐标区内点击
           ClickOn = app.UIAxes.CurrentPoint(1,1:2);
           if ClickOn(1) < app.UIAxes.XLim(1) || ClickOn(1) > app.UIAxes.XLim(2)
               return;
           end
           if ClickOn(2) < app.UIAxes.YLim(1) || ClickOn(2) > app.UIAxes.YLim(2)
               return;
           end
           
           %获取 时间和点坐标
           k = app.SelectedNum + 1;
           app.SelectedNum = k;
 
           %绘制 选择点
           switch app.dimension
               case 1
                     app.SelectedPointSet(k,2) = ClickOn(1);
                     app.SelectedPointSet(k,1) = ClickOn(2);
                     plot(app.UIAxes, app.SelectedPointSet(:,2),app.SelectedPointSet(:,1),'--xr');
               case 2
                     app.SelectedPointSet(k,1:app.dimension) = ClickOn(1:app.dimension);
                     app.SelectedPointSet(k,app.dimension+1) = k; %默认按次数作为时间
                     plot(app.UIAxes, app.SelectedPointSet(:,1),app.SelectedPointSet(:,2),'--xr');
           end
           %更新 Table表
           app.UITable.Data(k,:) = app.SelectedPointSet(k,:);
        end

        % Button pushed function: clearBUTTON
        function clearBUTTONButtonPushed(app, event)
            ClearData(app);
        end

        % Button pushed function: generateBUTTON
        function generateBUTTONButtonPushed(app, event)
            switch app.SelectMethor
                case '1'
                    switch app.dimension
                        case 1
                            [app.GeneratedPointSet(:,1),app.GeneratedTimeSet] = LFPB(app,app.SelectedPointSet(:,1),...
                                app.SelectedPointSet(:,2),app.a,app.SampleTime);
                            
                            plot(app.UIAxes,app.SelectedPointSet(:,2),app.SelectedPointSet(:,1),'--xr',...
                                app.GeneratedTimeSet,app.GeneratedPointSet(:,1),'-b');
                            
                        case 2
                            [app.GeneratedPointSet(:,1),app.GeneratedTimeSet] = LFPB(app,app.SelectedPointSet(:,1),...
                                app.SelectedPointSet(:,3),app.a,app.SampleTime);
                            
                            [app.GeneratedPointSet(:,2),app.GeneratedTimeSet] = LFPB(app,app.SelectedPointSet(:,2),...
                                app.SelectedPointSet(:,3),app.a,app.SampleTime);
                            
                            plot(app.UIAxes, app.SelectedPointSet(:,1),app.SelectedPointSet(:,2),'--xr',...
                                app.GeneratedPointSet(:,1),app.GeneratedPointSet(:,2),'-b');
                    end
                case '2'
                    
                case '3'
                    [app.GeneratedPointSet,app.GeneratedTimeSet] = B_spline(app,app.SelectedPointSet(:,1:app.dimension),...
                        app.SelectedPointSet(:,app.dimension+1),app.degree,app.SampleTime);
                    
                    plot(app.UIAxes, app.SelectedPointSet(:,1),...
                         app.SelectedPointSet(:,2),...
                         '--xr',...
                         app.GeneratedPointSet(:,1),app.GeneratedPointSet(:,2),'-b');
                    
            end          
        end

        % Value changed function: alimEDIT
        function alimEDITValueChanged(app, event)
            app.a = app.alimEDIT.Value;
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            data2save=[app.GeneratedPointSet';app.GeneratedTimeSet];
            %save('trajectory.mat','data2save');
            date=datestr(now,30);
            savepath=[app.path,'trajectory_',date,'.xlsx'];
            xlswrite(savepath,data2save');
            msgbox(savepath);
        end

        % Value changed function: pathField
        function pathFieldValueChanged(app, event)
            value = app.pathField.Value;
            app.path=value;
        end

        % Value changed function: selectBUTTON
        function selectBUTTONValueChanged(app, event)
            app.IsAllowToSelect = app.selectBUTTON.Value;
            if app.IsAllowToSelect
                app.selectBUTTON.Text = '已按下';
                app.selectBUTTON.FontColor = [0,0,0];
            else
                app.selectBUTTON.Text = '未按下';
                app.selectBUTTON.FontColor = [1.00,0,0];
            end
            
            
        end

        % Value changed function: originEDIT
        function originEDITValueChanged(app, event)
            value = app.originEDIT.Value;   
            dotposition = 0;
            flag=0;
            if length(value) <=2
                app.originEDIT.FontColor = [1.00,0,0];
                return;
            elseif value(1) == '[' && value(end) == ']'
                for k = 2:length(value)-1
                    if value(k) == ','
                        dotposition = k;
                        flag = 1;
                        break;
                    end
                end
            end
            if flag == 0
                app.originEDIT.FontColor = [1.00,0,0];
                return;
            else
                app.originEDIT.FontColor = [0,0,0];
                app.AxesLim(1) = str2double(value(2:dotposition-1));
                app.AxesLim(3) = str2double(value(dotposition+1:length(value)-1));
                app.UIAxes.XLim = app.AxesLim(1:2);
                app.UIAxes.YLim = app.AxesLim(3:4);
            end
        end

        % Value changed function: uprightEDIT
        function uprightEDITValueChanged(app, event)
            value = app.uprightEDIT.Value;   
            dotposition = 0;
            flag=0;
            if length(value) <=2
                app.uprightEDIT.FontColor = [1.00,0,0];
                return;
            elseif value(1) == '[' && value(end) == ']'
                for k = 2:length(value)-1
                    if value(k) == ','
                        dotposition = k;
                        flag = 1;
                        break;
                    end
                end
            end
            if flag == 0
                app.uprightEDIT.FontColor = [1.00,0,0];
                return;
            else
                app.uprightEDIT.FontColor = [0,0,0];
                app.AxesLim(2) = str2double(value(2:dotposition-1));
                app.AxesLim(4) = str2double(value(dotposition+1:length(value)-1));
                app.UIAxes.XLim = app.AxesLim(1:2);
                app.UIAxes.YLim = app.AxesLim(3:4);
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            app.SelectedPointSet(indices(1),indices(2)) = newData;

            plot(app.UIAxes, app.SelectedPointSet(:,1),app.SelectedPointSet(:,2),'-xr');
        end

        % Value changed function: methorSELECT
        function methorSELECTValueChanged(app, event)
            ClearData(app); %清空数据
            value = app.methorSELECT.Value;
            app.SelectMethor = value;
            switch value
                case '1'
                    %梯形规划，需要：加速度，维数。
                    edit_lable_switch(app,app.DegreeEditFieldLabel,app.degreeEDIT,'off');
                    edit_lable_switch(app,app.Vel_limEditFieldLabel,app.vlimEDIT,'off');
                    edit_lable_switch(app,app.Acc_limLabel,app.alimEDIT,'on');
                    edit_lable_switch(app,app.Jerk_limEditFieldLabel,app.JlimEDIT,'off');
                    
                    app.ButtonGroup.Buttons(1).Enable = 'on';
                    app.ButtonGroup.Buttons(2).Enable = 'on';
                    app.ButtonGroup.Buttons(3).Enable = 'off';
                case '2'
                    %双S规划，需要速度，加速度，加加速度。
                    edit_lable_switch(app,app.Vel_limEditFieldLabel,app.vlimEDIT,'on');
                    edit_lable_switch(app,app.Acc_limLabel,app.alimEDIT,'on');
                    edit_lable_switch(app,app.Jerk_limEditFieldLabel,app.JlimEDIT,'on');
                    edit_lable_switch(app,app.DegreeEditFieldLabel,app.degreeEDIT,'off');
                    
                    app.ButtonGroup.Buttons(1).Enable = 'on';
                    app.ButtonGroup.Buttons(2).Enable = 'off';
                    app.ButtonGroup.Buttons(3).Enable = 'off';
                case '3'
                    %B样条曲线
                    edit_lable_switch(app,app.DegreeEditFieldLabel,app.degreeEDIT,'on');
                    edit_lable_switch(app,app.Vel_limEditFieldLabel,app.vlimEDIT,'off');
                    edit_lable_switch(app,app.Acc_limLabel,app.alimEDIT,'off');
                    edit_lable_switch(app,app.Jerk_limEditFieldLabel,app.JlimEDIT,'off');
                    
                    app.ButtonGroup.Buttons(1).Enable = 'off';
                    app.ButtonGroup.Buttons(2).Enable = 'on';
                    app.ButtonGroup.Buttons(3).Enable = 'on';
            end
        end

        % Value changed function: vlimEDIT
        function vlimEDITValueChanged(app, event)
            app.velocity = app.vlimEDIT.Value;
        end

        % Value changed function: JlimEDIT
        function JlimEDITValueChanged(app, event)
            app.jerk = app.JlimEDIT.Value;
        end

        % Button pushed function: addBUTTON
        function addBUTTONPushed(app, event)
           k = app.SelectedNum + 1;
           app.SelectedNum = k;
           app.SelectedPointSet(k,1:(app.dimension+1)) = zeros(1,1+app.dimension) ;
           app.SelectedPointSet
           %更新 Table表
           app.UITable.Data(k,1:(app.dimension+1)) = zeros(1,1+app.dimension);
           %绘制 选择点
           plot(app.UIAxes, app.SelectedPointSet(:,1),app.SelectedPointSet(:,2),'-xr');
        end

        % Value changed function: degreeEDIT
        function degreeEDITValueChanged(app, event)
           app.degree = app.degreeEDIT.Value;
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            value = app.ButtonGroup.SelectedObject;
            app.dimension = str2double(value.Text) ;
            ClearData(app);
            switch app.dimension
                case 1
                    app.UIAxes.XLabel.String = 't';
                    app.UITable.ColumnName = [{'y'},{'t'}];
                    app.UITable.ColumnWidth = [{app.tablewidth/2},{app.tablewidth/2}];
                    app.UIAxes.View = [0, 90];
                case 2
                    app.UIAxes.XLabel.String = 'x';
                    app.UITable.ColumnName = [{'x'},{'y'},{'t'}];
                    app.UITable.ColumnWidth = [{app.tablewidth/3},{app.tablewidth/3},{app.tablewidth/3}];
                    app.UIAxes.View = [0, 90];
                case 3
                    app.UIAxes.XLabel.String = 'x';
                    app.UITable.ColumnName = [{'x'},{'y'},{'z'},{'t'}];
                    app.UITable.ColumnWidth = [{app.tablewidth/4},{app.tablewidth/4},{app.tablewidth/4},{app.tablewidth/4}];
                    app.UIAxes.View = [-60, 60];    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajectory Planning Graph')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.xyEditFieldLabel = uilabel(app.UIFigure);
            app.xyEditFieldLabel.Text = 'x,y';
            app.xyEditField = uieditfield(app.UIFigure, 'text');
            app.generateBUTTON = uibutton(app.UIFigure, 'push');
            app.generateBUTTON.ButtonPushedFcn = createCallbackFcn(app, @generateBUTTONButtonPushed, true);
            app.generateBUTTON.Text = 'generate';
            app.clearBUTTON = uibutton(app.UIFigure, 'push');
            app.clearBUTTON.ButtonPushedFcn = createCallbackFcn(app, @clearBUTTONButtonPushed, true);
            app.clearBUTTON.Text = 'clear';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'; 't'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = false;
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.Acc_limLabel = uilabel(app.UIFigure);
            app.Acc_limLabel.Text = 'Acc_lim';
            app.alimEDIT = uieditfield(app.UIFigure, 'numeric');
            app.alimEDIT.ValueChangedFcn = createCallbackFcn(app, @alimEDITValueChanged, true);
            app.methorLabel = uilabel(app.UIFigure);
            app.methorLabel.Text = 'methor';
            app.methorSELECT = uidropdown(app.UIFigure);
            app.methorSELECT.Items = {'Trapezoidal', 'DoubleS', 'B-Spline'};
            app.methorSELECT.ItemsData = {'1', '2', '3'};
            app.methorSELECT.ValueChangedFcn = createCallbackFcn(app, @methorSELECTValueChanged, true);
            app.methorSELECT.Value = '2';
            app.saveButton = uibutton(app.UIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save';
            app.pathLabel = uilabel(app.UIFigure);
            app.pathLabel.Text = 'path：';
            app.pathField = uieditfield(app.UIFigure, 'text');
            app.pathField.ValueChangedFcn = createCallbackFcn(app, @pathFieldValueChanged, true);
            app.pathField.Value = 'D:\';
            app.selectBUTTON = uibutton(app.UIFigure, 'state');
            app.selectBUTTON.ValueChangedFcn = createCallbackFcn(app, @selectBUTTONValueChanged, true);
            app.selectBUTTON.Tooltip = {'按下按钮后才允许选取点。'};
            app.selectBUTTON.Text = '未按下';
            app.OriginLabel = uilabel(app.UIFigure);
            app.OriginLabel.Text = 'Origin';
            app.originEDIT = uieditfield(app.UIFigure, 'text');
            app.originEDIT.ValueChangedFcn = createCallbackFcn(app, @originEDITValueChanged, true);
            app.originEDIT.Value = '[0,0]';
            app.UpperRightLabel = uilabel(app.UIFigure);
            app.UpperRightLabel.Text = 'Upper Right';
            app.uprightEDIT = uieditfield(app.UIFigure, 'text');
            app.uprightEDIT.ValueChangedFcn = createCallbackFcn(app, @uprightEDITValueChanged, true);
            app.uprightEDIT.Value = '[1,1]';
            app.DegreeEditFieldLabel = uilabel(app.UIFigure);
            app.DegreeEditFieldLabel.Visible = 'off';
            app.DegreeEditFieldLabel.Text = 'Degree';
            app.degreeEDIT = uieditfield(app.UIFigure, 'numeric');
            app.degreeEDIT.ValueChangedFcn = createCallbackFcn(app, @degreeEDITValueChanged, true);
            app.degreeEDIT.Visible = 'off';
            app.degreeEDIT.Value = 3;
            app.Vel_limEditFieldLabel = uilabel(app.UIFigure);
            app.Vel_limEditFieldLabel.Text = 'Vel_lim';
            app.vlimEDIT = uieditfield(app.UIFigure, 'numeric');
            app.vlimEDIT.ValueChangedFcn = createCallbackFcn(app, @vlimEDITValueChanged, true);
            app.Jerk_limEditFieldLabel = uilabel(app.UIFigure);
            app.Jerk_limEditFieldLabel.Text = 'Jerk_lim';
            app.JlimEDIT = uieditfield(app.UIFigure, 'numeric');
            app.JlimEDIT.ValueChangedFcn = createCallbackFcn(app, @JlimEDITValueChanged, true);
            app.dtEditFieldLabel = uilabel(app.UIFigure);
            app.dtEditFieldLabel.Text = 'dt';
            app.dtEDIT = uieditfield(app.UIFigure, 'numeric');
            app.dtEDIT.Value = 0.01;
            app.addBUTTON = uibutton(app.UIFigure, 'push');
            app.addBUTTON.ButtonPushedFcn = createCallbackFcn(app, @addBUTTONPushed, true);
            app.addBUTTON.Text = 'add';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Enable = 'off';
            app.Button_3.Text = '3';
            app.DimensionLabel = uilabel(app.UIFigure);
            app.DimensionLabel.Text = 'Dimension';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trajectory_planning

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end